classdef Hyscorean < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HyscoreanFigure             matlab.ui.Figure
        TraceButtonGroup            matlab.ui.container.ButtonGroup
        PlotApodizationWindow       matlab.ui.control.RadioButton
        PreProcessedTrace           matlab.ui.control.RadioButton
        NonCorrectedTrace           matlab.ui.control.RadioButton
        ChangeSignalPlotDimension   matlab.ui.control.CheckBox
        Image                       matlab.ui.control.Image
        text97                      matlab.ui.control.Label
        GPS_button                  matlab.ui.control.StateButton
        ImaginaryTrace              matlab.ui.control.CheckBox
        ZoomOutButton               matlab.ui.control.Button
        ZoomButton                  matlab.ui.control.Button
        EasyspinFitButton           matlab.ui.control.Button
        CopyrightText               matlab.ui.control.Label
        AddOnsPanel                 matlab.ui.container.Panel
        ImposeBlindSpots            matlab.ui.control.CheckBox
        AddTagList                  matlab.ui.control.DropDown
        BlindSpotsSimulator         matlab.ui.control.Button
        FieldOffsetTag              matlab.ui.control.Label
        FieldOffset                 matlab.ui.control.EditField
        ClearTags                   matlab.ui.control.Button
        TransititonType             matlab.ui.control.DropDown
        AddHelpLine                 matlab.ui.control.Button
        DetachSignalPlot            matlab.ui.control.Button
        text77                      matlab.ui.control.Label
        text76                      matlab.ui.control.Label
        SaverSettings               matlab.ui.control.Button
        LoadSettings                matlab.ui.control.Button
        SaveSettingsButton          matlab.ui.control.Button
        DisplayLoadedFiles          matlab.ui.control.Button
        SaveReportButton            matlab.ui.control.Button
        t1_Slider                   matlab.ui.control.Slider
        LoadedData                  matlab.ui.control.Label
        LoadButton                  matlab.ui.control.Button
        trace2Info                  matlab.ui.control.Label
        uipanel6                    matlab.ui.container.Panel
        ReconstructionLamp          matlab.ui.control.Lamp
        BackgroundCorrectionLamp    matlab.ui.control.Lamp
        TauSelectionLamp            matlab.ui.control.Lamp
        InvertCorrection            matlab.ui.control.CheckBox
        text95                      matlab.ui.control.Label
        text94                      matlab.ui.control.Label
        Validation_Button           matlab.ui.control.Button
        plotNUSgrid                 matlab.ui.control.Button
        plotNUSgridText             matlab.ui.control.Label
        BackgroundStart2            matlab.ui.control.EditField
        BackgroundStart1            matlab.ui.control.EditField
        text82                      matlab.ui.control.Label
        text43                      matlab.ui.control.Label
        MultiTauDimensions          matlab.ui.control.DropDown
        MaxEntLagrangianMultiplier  matlab.ui.control.EditField
        LagrangeMultiplierText      matlab.ui.control.Label
        MaxEntBackgroundParameter   matlab.ui.control.EditField
        BackgroundParameterText     matlab.ui.control.Label
        ReconstructionAlgorithm     matlab.ui.control.DropDown
        NUSReconstructionText       matlab.ui.control.Label
        BackgroundParameterText2    matlab.ui.control.Label
        BackgroundParameterText1    matlab.ui.control.Label
        text47                      matlab.ui.control.Label
        text46                      matlab.ui.control.Label
        BackgroundParameter2        matlab.ui.control.EditField
        BackgroundParameter1        matlab.ui.control.EditField
        BackgroundMethod2           matlab.ui.control.DropDown
        BackgroundMethod1           matlab.ui.control.DropDown
        TauSelectionText            matlab.ui.control.Label
        GraphicsPanel               matlab.ui.container.Panel
        text96                      matlab.ui.control.Label
        MaximalContourLevel         matlab.ui.control.EditField
        DetachProjectionPlotText    matlab.ui.control.Label
        DetachProjectionPlot        matlab.ui.control.Button
        text80                      matlab.ui.control.Label
        MinimalContourLevel         matlab.ui.control.EditField
        text79                      matlab.ui.control.Label
        text78                      matlab.ui.control.Label
        XUpperLimit                 matlab.ui.control.EditField
        text71                      matlab.ui.control.Label
        GraphicalSettingsButton     matlab.ui.control.Button
        text40                      matlab.ui.control.Label
        detachMainSurface           matlab.ui.control.Button
        text39                      matlab.ui.control.Label
        detachMainContour           matlab.ui.control.Button
        uipanel8                    matlab.ui.container.Panel
        WindowLength2               matlab.ui.control.EditField
        WindowLength1               matlab.ui.control.EditField
        WindowLengthText2           matlab.ui.control.Label
        WindowLengthText1           matlab.ui.control.Label
        Symmetrization_ListBox      matlab.ui.control.DropDown
        SymmetrizationText          matlab.ui.control.Label
        WindowType                  matlab.ui.control.DropDown
        Lorentz2GaussCheck          matlab.ui.control.CheckBox
        ProcessingInfo              matlab.ui.control.Label
        ProcessButton               matlab.ui.control.Button
        L2G_tau2                    matlab.ui.control.EditField
        L2G_sigma2                  matlab.ui.control.EditField
        L2GTauText2                 matlab.ui.control.Label
        L2GSigmaText2               matlab.ui.control.Label
        L2G_tau                     matlab.ui.control.EditField
        L2G_sigma                   matlab.ui.control.EditField
        ZeroFilling2_Text           matlab.ui.control.Label
        ZeroFilling2                matlab.ui.control.EditField
        ZeroFilling1_Text           matlab.ui.control.Label
        ZeroFilling1                matlab.ui.control.EditField
        text61                      matlab.ui.control.Label
        text60                      matlab.ui.control.Label
        L2GTauText                  matlab.ui.control.Label
        L2GSigmaText                matlab.ui.control.Label
        uipanel9                    matlab.ui.container.Panel
        signal_t1                   matlab.ui.control.UIAxes
        mainPlot                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Data      % Main global data pool for Hyscorean
        Processed = []
        FileNames
        FilePaths
        Selections
    
        GraphicalSettings
        IsotopeTags = struct('isotope', {}, 'name', {}, 'Color', {}, 'gn', {})
        FrequencyMultiplier = 1
        SymmetrizationString = "None"
        WindowTypeString = "chebyshev"
    
        TauSelectionSwitch = true
        backgroundCorrectionSwitch = true
        ReconstructionSwitch = true
        MountDataSwitch = true
    
        PlotImaginarySignal = false
        PlotProcessedSignal = false
        PlotBackground = false
        PlotWithZeroFilling = false
        SignalPlotIsDetached = false
    
        AddedLines = {}
        AddedTags = {}
    
        SaveHyscoreanSettings = struct()

        currentIndexes
        currentTaus
        Diagonals
        VerticalLine
        amber_color = [0.95 0.55 0.15];
        green_color = [0.0 1.0 0.0];
        gray_color =  [0.7 0.7 0.7];

    end
    
    methods (Access = private)
        function initializeAddTagList(app)
            % Initialize AddTagList once during app startup.
            % Migrated from old GUIDE AddTagList_CreateFcn.
        
            % Read isotope/element table
            data = ReadDataTable_Hyscorean;
        
            % Build isotope tag struct array from valid nuclei
            iso_Tags = struct('isotope', {}, 'name', {}, 'Color', {}, 'gn', {});
            j = 1;
        
            % Equivalent of Colors = white(length(data.Element)) - 1;
            % -> all zeros, so resulting uint8 color is [0 0 0]
            Colors = zeros(length(data.Element), 3);
        
            for i = 1:length(data.Element)
                if (data.Spin(i) ~= 0) && (data.Nucleons(i) ~= 0)
                    iso_Tags(j).isotope = num2str(data.Nucleons(i));
                    iso_Tags(j).name    = data.Element{i};
                    iso_Tags(j).Color   = uint8(Colors(i,:) * 255 + 0.5);
                    iso_Tags(j).gn      = data.gn(i);
                    j = j + 1;
                end
            end
        
            % Store in app state
            app.IsotopeTags = iso_Tags;
        
            % Build plain-text item strings for App Designer
            % Old GUIDE used HTML formatting, which App Designer items do not support.
            listItems = cell(numel(iso_Tags), 1);
            for i = 1:numel(iso_Tags)
                listItems{i} = [iso_Tags(i).isotope iso_Tags(i).name];
            end
        
            % Assign items to the App Designer component
            app.AddTagList.Items = listItems;
        
            % Set a default selected value if list is non-empty
            if ~isempty(listItems)
                app.AddTagList.Value = listItems{1};
            end
        end

        function resetPlots(app)
            %==========================================================================
            
            
            %Reset main display plot as in the beginning
            plot(app.mainPlot,-50:1:50,abs(-50:1:50),'k-.'),grid(app.mainPlot,'on')
            hold(app.mainPlot,'on')
            plot(app.mainPlot,zeros(length(0:50),1),abs(0:50),'k-')
            hold(app.mainPlot,'off')
            app.mainPlot.XTickLabel = {};
            app.mainPlot.YTickLabel = {};
            
            %Reset signal plot as in the beginning
            cla(app.signal_t1,'reset')
            app.signal_t1.XTick = [];
            app.signal_t1.YTick = [];
            hold(app.signal_t1,'on')
            plot(app.signal_t1,-50:1:50,abs(-50:0.5:0),'k-',-50:1:50,abs(0:0.5:50),'k-')
            app.signal_t1.XTickLabel = {};
            app.signal_t1.YTickLabel = {};
            hold(app.signal_t1,'off')
            return

            %OLD GUIDE
            %Reset main display plot as in the beginning
            % plot(handles.mainPlot,-50:1:50,abs(-50:1:50),'k-.'),grid(handles.mainPlot,'on')
            % hold(handles.mainPlot,'on')
            % plot(handles.mainPlot,zeros(length(0:50),1),abs(0:50),'k-')
            % hold(handles.mainPlot,'off')
            % set(handles.mainPlot,'xticklabel',[],'yticklabel',[])
            % 
            % %Reset signal plot as in the beginning
            % cla(handles.signal_t1,'reset')
            % set(handles.signal_t1,'xtick',[],'ytick',[])
            % hold(handles.mainPlot,'on')
            % plot(handles.signal_t1,-50:1:50,abs(-50:0.5:0),'k-',-50:1:50,abs(0:0.5:50),'k-')
            % set(handles.signal_t1,'xticklabel',[],'yticklabel',[])

        end
        
        function zoompostcallback(app) %, hObject, ~, eventdata, handles)
            %==========================================================================
            
            % xax = get(app.mainPlot,'XLim');
            % yax = get(app.mainPlot,'YLim');

            % xax = app.mainPlot.XLim;
            yax = app.mainPlot.YLim;
            if yax(1) < 0
                yax(1) = 0.0;
            end
            % set(app.mainPlot,'YLim',yax);
            app.mainPlot.YLim = yax;
            return
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Hyscorean_OpeningFcn(app, varargin)
            %==========================================================================

            %Check that the license has been accepted, otherwise kill the startup
            if ispref('hyscorean','LGPL_license')
                if ~getpref('hyscorean','LGPL_license')
                    uialert(app.UIFigure, 'Hyscorean''s GNU LGPL 3.0 license agreement not accepted. Please run setup_hyscorean again and accept the license agreemen.','Warning','Icon','warning');
                    drawnow;   % ensures alert renders
                    delete(app);     % closes UIFigure and frees the app
                    return
                end
            else
                uialert(app.UIFigure, 'Hyscorean''s GNU LGPL 3.0 license agreement not found. Please run setup_hyscorean again and accept the license agreemen.','Warning','Icon','warning');
                drawnow;   % ensures alert renders
                delete(app);     % closes UIFigure and frees the app
                return
            end

            app.GraphicalSettings = getpref('hyscorean','graphicalsettings');
            if ~isfield(app.GraphicalSettings,'ColormapName')
                app.GraphicalSettings.ColormapName = 'parula';
                setpref('hyscorean','graphicalsettings',app.GraphicalSettings);
            end

            % Ensure that the app appears on screen when run
            movegui(app.HyscoreanFigure, 'onscreen');

            % app Fig Icon
            app.HyscoreanFigure.Icon = fullfile(fileparts(mfilename("fullpath")), "docs", "img", "logo50.png"); % or logo50.png

            initializeAddTagList(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            % 
            % setFigureIcon(hObject);
            % handles.output = hObject;
            % guidata(hObject, handles);
        end

        % Button pushed function: AddHelpLine
        function AddHelpLine_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %Get gyromagnetic ratio from selected nuclei
            gyromagneticRatio = getgyro_Hyscorean(app.AddTagList.Value, app.IsotopeTags);
            %Get center field in gauss
            try
              if isfield(app.Data,'BrukerParameters')
                CenterField = app.Data.BrukerParameters.CenterField;
                %Remove units character and convert to double
                CenterField = str2double(CenterField(1:end-2));
              elseif isfield(app.Data,'AWG_Parameters')
                CenterField = app.Data.AWG_Parameters.B;
              end
            
            catch
              uialert(app.HyscoreanFigure, 'The experimental parameters could not be extracted from the loaded file.','File Error', 'Icon', 'error');
              drawnow;
            end
            %convert to tesla
            CenterField = CenterField*1e-4;
            %Get field offset
            Offset = app.FieldOffset.Value;
            Offset = str2double(Offset)*1e-4;
            
            %Get type of transition and corresponding multiplier
            FrequencyMultip = app.FrequencyMultiplier;
            %get Larmor frequency in MHz
            Larmorfrequency = FrequencyMultip*gyromagneticRatio*(CenterField + Offset);
            X = Larmorfrequency;
            Y = abs(Larmorfrequency);
            
              Xaxis = app.Processed.axis1;
            if X>0
              Xaxis = Xaxis(Xaxis>0);
              Slope = -1;
            else
                Xaxis = Xaxis(Xaxis>0);
                Slope = -1;
            end
            
            switch FrequencyMultip
              case 1
                color = 'k';
              case 2
                color = 'r';
              case 4
                color = 'b';
            end
            Yaxis =  Y + Slope*(Xaxis - abs(X));
            hold(app.mainPlot,'on')
            LineHandle = plot(app.mainPlot,Xaxis,Yaxis,'-.','LineWidth',1.5,'Color',color);
            hold(app.mainPlot,'off')
            if ~isempty(app.AddedLines)
                size = length(app.AddedLines);
            else
                size = 0;
            end
            app.AddedLines{size +1}.x = Xaxis;
            app.AddedLines{size +1}.y = Yaxis;
            app.AddedLines{size +1}.handle = LineHandle;
            % guidata(hObject, handles);
            return
        end

        % Value changed function: BackgroundMethod1
        function BackgroundMethod1_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            method = string(app.BackgroundMethod1.Value);

            switch method
              case ''
                app.BackgroundParameterText1 = '';
                app.BackgroundParameter1.Visible = 'off';
              case 'Fractal'
                app.BackgroundParameterText1.Text = 'Fractal Dimension';
                app.BackgroundParameter1.Visible = 'on';
                app.BackgroundParameter1.Enable = 'on';
                app.BackgroundParameter1.Value = '1';
              case 'Polynomial'
                app.BackgroundParameterText1.Text = 'Polynomial Order';
                app.BackgroundParameter1.Value = '1';
                app.BackgroundParameter1.Visible = 'on';
                app.BackgroundParameter1.Enable = 'on';
              case 'Exponential'
                app.BackgroundParameterText1.Text = 'Exponential Order';
                app.BackgroundParameter1.Enable = 'on';
                app.BackgroundParameter1.Visible = 'on';
                app.BackgroundParameter1.Value = '1';
            end
            app.backgroundCorrectionSwitch = true;
            app.ReconstructionSwitch  = true;
            app.BackgroundCorrectionLamp.Color = app.gray_color;
            app.ReconstructionLamp.Color = app.gray_color;
            % guidata(hObject, handles);
            return
        end

        % Value changed function: BackgroundMethod2
        function BackgroundMethod2_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            method = string(app.BackgroundMethod2.Value);

            switch method
              case ''
                app.BackgroundParameterText2 = '';
                app.BackgroundParameter2.Visible = 'off';
              case 'Fractal'
                app.BackgroundParameterText2.Text = 'Fractal Dimension';
                app.BackgroundParameter2.Visible = 'on';
                app.BackgroundParameter2.Enable = 'on';
                app.BackgroundParameter2.Value = '1';
              case 'Polynomial'
                app.BackgroundParameterText2.Text = 'Polynomial Order';
                app.BackgroundParameter2.Value = '1';
                app.BackgroundParameter2.Visible = 'on';
                app.BackgroundParameter2.Enable = 'on';
              case 'Exponential'
                app.BackgroundParameterText2.Text = 'Exponential Order';
                app.BackgroundParameter2.Enable = 'on';
                app.BackgroundParameter2.Visible = 'on';
                app.BackgroundParameter2.Value = '1';
            end
            app.backgroundCorrectionSwitch = true;
            app.ReconstructionSwitch  = true;
            app.BackgroundCorrectionLamp.Color = app.gray_color;
            app.ReconstructionLamp.Color = app.gray_color;
            % guidata(hObject, handles);
            return
        end

        % Value changed function: BackgroundParameter1
        function BackgroundParameter1_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.backgroundCorrectionSwitch = true;
            app.ReconstructionSwitch  = true;
            app.BackgroundCorrectionLamp.Color = app.amber_color;
            app.ReconstructionLamp.Color = app.amber_color;
            % guidata(hObject, handles);
            return
        end

        % Value changed function: BackgroundParameter2
        function BackgroundParameter2_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.backgroundCorrectionSwitch = true;
            app.ReconstructionSwitch  = true;
            app.BackgroundCorrectionLamp.Color = app.amber_color;
            app.ReconstructionLamp.Color = app.amber_color;
            % guidata(hObject, handles);
            return
        end

        % Value changed function: BackgroundStart1
        function BackgroundStart1_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if str2double(app.BackgroundStart1.Value)<1
               app.BackgroundStart1.Value = 1;
            end
            app.backgroundCorrectionSwitch = true;
            app.ReconstructionSwitch = true;
            app.BackgroundCorrectionLamp.Color = app.amber_color;
            app.ReconstructionLamp.Color = app.amber_color;
            % guidata(hObject, handles);
            return
        end

        % Value changed function: BackgroundStart2
        function BackgroundStart2_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if str2double(app.BackgroundStart2.Value)<1
               app.BackgroundStart2.Value = 1;
            end
            app.backgroundCorrectionSwitch = true;
            app.ReconstructionSwitch = true;
            app.BackgroundCorrectionLamp.Color = app.amber_color;
            app.ReconstructionLamp.Color = app.amber_color;
            % guidata(hObject, handles);
            return
        end

        % Button pushed function: BlindSpotsSimulator
        function BlindSpotsSimulator_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if ~isempty(app.Processed)
                if app.Data.exptype == '6pHYSCORE'
                    warndlg('Blindspot simulation uses only 2nd tau-value of loaded 6pHYSCORE and gives blindspots of a 4pHYSCORE','warning');
                end
                Blindspot_simulator(app.Processed.axis1,app.Processed.axis2,app.Processed.spectrum,str2double(app.XUpperLimit.Value),app.Processed.ContourLevels);
            else
                xaxis = linspace(app.mainPlot.XLim(1),app.mainPlot.XLim(2));
                yaxis = linspace(app.mainPlot.YLim(1),app.mainPlot.YLim(2));
                Blindspot_simulator(xaxis,yaxis)
            end
            return
        end

        % Button pushed function: ClearTags
        function ClearTags_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            try
              for i=1:length(app.AddedLines)
              delete(app.AddedLines{i}.handle)
              end
              app.AddedLines = {};
            catch
            end
            try
              for i=1:length(app.AddedTags)
              delete(app.AddedTags{i}.handle)
              end
            app.AddedTags = {};
            catch
            end
            % guidata(hObject, handles);
            return
        end

        % Button pushed function: DetachProjectionPlot
        function DetachProjectionPlot_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %Find figure, close it and open it again
            Figure = findobj('Tag','mainProjectionDetached');
            if isempty(Figure)
              Figure = figure('Tag','mainProjectionDetached','WindowStyle','normal');
            else
              figure(Figure);
              clf(Figure);
            end
            %Use Hyscorean window logo
            setFigureIcon(Figure);
            %Make the window appear relative to the Hyscorean window
            Position = handles.HyscoreanFigure.Position;
            Position(1) = Position(1)+500;
            Position(2) = Position(2)+60;
            options.figsize = [500 500 790 450];
            set(Figure,'NumberTitle','off','Name','Hyscorean: Projection Contour','Units','pixels','Position',options.figsize);
              XUpperLimit=str2double(get(handles.XUpperLimit,'string'));
              options.xaxs = [-XUpperLimit XUpperLimit]; options.yaxs = [0 XUpperLimit];
              options.xlabel = '\nu_1 [MHz]'; options.ylabel = '\nu_2 [MHz]';
            options.levels=app.GraphicalSettings.Levels;
            options.Linewidth=app.GraphicalSettings.LineWidth;
            options.nonewfig = true;
            options.MinimalContourLevel = str2double(get(handles.MinimalContourLevel,'string'));
            options.MaximalContourLevel = str2double(get(handles.MaximalContourLevel,'string'));
            
            colormap(app.GraphicalSettings.ColormapName)
            if app.GraphicalSettings.Absolute
              spectrum2 = abs(handles.Processed.spectrum);
            elseif app.GraphicalSettings.Real
              spectrum2 = real(handles.Processed.spectrum);
            elseif app.GraphicalSettings.Imaginary
              spectrum2 = imag(handles.Processed.spectrum);
            end
            Hyscore_correlation_plot(handles.Processed.axis2,handles.Processed.axis1,spectrum2,options)
            return
        end

        % Button pushed function: DetachSignalPlot
        function DetachSignalPlot_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            setappdata(0,'Processed',handles.Processed)
            setappdata(0,'Data',handles.Data)
            setappdata(0,'InvertCorrection',get(handles.InvertCorrection,'value'))
            setappdata(0,'ZeroFilling1',str2double(get(handles.ZeroFilling1,'String')))
            setappdata(0,'ZeroFilling2',str2double(get(handles.ZeroFilling2,'String')))
            setappdata(0,'WindowLength1',get(handles.WindowLength1,'String'))
            setappdata(0,'WindowLength2',get(handles.WindowLength2,'String'))
            setappdata(0,'WindowType',get(handles.WindowType,'Value'))
            
            %Call graphical settings GUI
            handles.SignalPlotIsDetached = true;
            guidata(hObject, handles);
            
            ScreenSize = get(0,'ScreenSize');
            Position = handles.HyscoreanFigure.Position;
            Position(1) = Position(1)/ScreenSize(3) + 0.1;
            Position(2) = Position(2)/ScreenSize(4) + 0.1;
            Position(3) = 790.0/ScreenSize(3);
            Position(4) = 463.0/ScreenSize(4);
            PrePro
            uiwait(Hyscorean_detachedSignalPlot('Units','normalized','Position',Position))
            
            handles.SignalPlotIsDetached = false;
            guidata(hObject, handles);
            
            return
        end

        % Button pushed function: DisplayLoadedFiles
        function DisplayLoadedFiles_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Display a list with all loaded files in the program
            try
              [app.FileNames, app.FilePaths, CancelFlag] = listLoadedFiles(app.FileNames, app.FilePaths);
              if CancelFlag
                %If data has been reloaded and there exists and old processed spectrum, remove all the associated data
                if ~isempty(app.Processed)
                  app.Processed = [];
                end
                %Reset/Disable graphical handles so that no errors appear if called
                app.PreProcessedTrace.Visible = 'off';
                app.NonCorrectedTrace.Visible = 'off';
                app.PlotApodizationWindow.Visible = 'off';
                app.DetachSignalPlot.Visible = 'off';
                app.ChangeSignalPlotDimension.Visible = 'off';
                app.t1_Slider.Enable = 'off';
                app.ImposeBlindSpots.Enable = 'off';
                app.AddHelpLine.Enable = 'off';
                app.TransititonType.Enable = 'off';
                app.AddTagList.Enable = 'off';
                app.ClearTags.Enable = 'off';
                app.FieldOffsetTag.Enable = 'off';
                app.FieldOffset.Enable = 'off';
                set(findall(app.GraphicsPanel, '-property', 'Enable'), 'Enable', 'off');
                app.trace2Info.Text = '';
                app.TauSelectionSwitch = true;
                app.backgroundCorrectionSwitch = true;
                app.ReconstructionSwitch  = true;
                app.MountDataSwitch  = true;
                app.TauSelectionLamp.Color = app.gray_color;
                app.BackgroundCorrectionLamp.Color = app.gray_color;
                app.ReconstructionLamp.Color = app.gray_color;
                enableDisableGUI(app,'NUSReconstruction','off')
            
                %Reset maind and signal plots to its startup state
                resetPlots(app);
            
                %Inform the user of the remaining files
                app.LoadedData.Text = sprintf('%d File(s) Loaded',length(app.FileNames));

                %Force rendering of the GUI
                drawnow
            
                %Mount data again and get tau values
                app.Data = mountHYSCOREdata(app.FileNames, app);
                TauValues = app.Data.TauValues;
            
                %Get again combinations and set the corresponding UI element values
                [app.Selections,app.Data.Combinations] = getTauCombinations(TauValues);
                app.MultiTauDimensions.Enable = 'on';
                app.MultiTauDimensions.Text = app.Selections;
                app.ZeroFilling1.Value = string(2*size(app.Data.TauSignals,2));
                app.ZeroFilling2.Value = string(2*size(app.Data.TauSignals,3));
                app.WindowLength1.Value = string(size(app.Data.TauSignals,2));
                app.WindowLength2.Value = string(size(app.Data.TauSignals,3));
            
                %Inform the user and return
                app.ProcessingInfo = 'Status: Ready'; drawnow;
            
              end
            catch
            end
            % guidata(hObject, handles);
            return
        end

        % Button pushed function: EasyspinFitButton
        function EasyspinFitButton_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if app.Data.exptype == '6pHYSCORE'
                warndlg('Fitting is programmed for 4P HYSCORE, fitting of loaded 6P HYSCORE might lead to errors','warning');
            end
            
            %Fill known experimental parameters
            Exp.Sequence = 'HYSCORE';
            if ~isempty(app.Data) && isfield(app.Data,'BrukerParameters')
              BrukerParameters = app.Data.BrukerParameters;
              [Param] = brukerparam(BrukerParameters);
              Exp.Field = Param.Centerfield;
              Exp.mwFreq = Param.mwFreq;
              FirstPulseLength = Param.Pulse90;
            elseif ~isempty(app.Data) && isfield(app.Data,'AWG_Parameters')
              Exp.Field =  0.1*app.Data.AWG_Parameters.B;
              Exp.mwFreq = sum(app.Data.AWG_Parameters.LO) + app.Data.AWG_Parameters.nu_obs;
              FirstPulseLength = app.Data.AWG_Parameters.events{1}.pulsedef.tp/1000;
            end
            %Set the excitation bandwidth [GHz] to the inverse of the first pulse employed
            Exp.ExciteWidth = 1/FirstPulseLength;
            %Compute the corrected magnetic field [mT]
            Offset = str2double(app.FieldOffset.Value);
            Exp.Field = Exp.Field + 0.1*Offset;
            Exp.tau = app.currentTaus/1000;
            Exp.dt = app.Data.TimeStep1;
            Exp.nPoints = length(app.Data.PreProcessedSignal);
            
            %Fill known processing parameters
            if ~iscell(app.FilePaths.Path)
              Opt.FileNames = {app.FilePaths.Files};
            else
              Opt.FileNames = app.FilePaths.Files;
            end
            if ~iscell(app.FilePaths.Files)
              Opt.FilePaths = {app.FilePaths.Path};
            else
              Opt.FilePaths = app.FilePaths.Path;
            end
            Opt.GridSize = 181;
            Opt.ZeroFillFactor = length(app.Processed.Signal)/length(app.Data.PreProcessedSignal);
            Opt.FreqLim = str2double(app.XUpperLimit.Value);
            Opt.WindowType = app.WindowTypeString;
            Opt.WindowDecay1 = str2double(app.WindowLength1.Value);
            Opt.WindowDecay2 = str2double(app.WindowLength2.Value);
            Opt.L2GParameters.tauFactor2 = str2double(app.L2G_tau2.Value);
            Opt.L2GParameters.sigmaFactor2 = str2double(app.L2G_sigma2.Value);
            Opt.L2GParameters.tauFactor1 = str2double(app.L2G_tau.Value);
            Opt.L2GParameters.sigmaFactor1 = str2double(app.L2G_sigma.Value);
            Opt.Lorentz2GaussCheck = app.Lorentz2GaussCheck.Value;
            Opt.Symmetrization = app.SymmetrizationString;
            Opt.TimeStepFactor = 1;
            %Launch the fitting module
            esfit_hyscorean('saffron',abs(app.Processed.spectrum),[],[],Exp,Opt)
            
            return
        end

        % Value changed function: GPS_button
        function GPS_button_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Check if button is toogled
            if event.Value
            
              %Start continuous execution
              while true
                %Get mouse pointer location in root
                ScreenPos = get(0, 'PointerLocation');
                %Set main figure axis to normalized units
                app.mainPlot.Units = 'Normalized';
                %Get relative position of axis in screen
                Transform = app.mainPlot.Position;
                %Get main axis parent
                Parent = app.mainPlot.Parent;
                %Loop all the way up the hierarchy to the root
                while true
                  if strcmp(get(Parent,'type'),'root')
                    parentPos = get(Parent,'ScreenSize');  % Save screen units
                  else
                    set(Parent,'Units','Normalized'); % Norm units
                    parentPos = get(Parent,'Position'); % Norm units
                  end
                  %Adapt transform vector
                  Transform(1:2) = parentPos(1:2) + parentPos(3:4).*Transform(1:2);
                  Transform(3:4) = parentPos(3:4).*Transform(3:4);
                  Parent = get(Parent,'Parent');
                  %If already reached the root, stop
                  if ~strcmp(get(Parent,'type'),'root')
                    break
                  end
                end
                %Translate pointer position from screen to axis coordinates
                AxisPos = (ScreenPos - Transform(1:2))./Transform(3:4);
                %Get axis values
                AxesYLim=app.mainPlot.YLim;
            	AxesXLim=app.mainPlot.XLim;
                AxesValuesYSpan=AxesYLim(2)-AxesYLim(1);
                AxesValuesXSpan = AxesXLim(2)-AxesXLim(1);
            
                %Check if graphics handles exist and delete them
                if exist('AnnotationHandle','var')
                  delete(AnnotationHandle)
                  delete(AnnotationHandle2)
                  delete(LineHandle)
                  delete(RectangleHandle)
                  delete(PointHandle)
                end
            
                %Update datatip only if pointer is inside the axes
                if AxisPos(1)>=0 &&  AxisPos(1)<=1 &&...
                    AxisPos(2)>=0 &&  AxisPos(2)<=1
            
                  %Compute the frequency coordinates from relative axis position
                  y = AxisPos(2)*AxesValuesYSpan+AxesYLim(1);
                  x = AxisPos(1)*AxesValuesXSpan+AxesXLim(1);
                  %Differentiate between positive and negative quadrants
                  if  x > 0
                    Sign = -1;
            
                    %Get diagonal reflection values
                    x2 = y;
                    y2 = x;
                    SideOffset = 1;
                  else
                    Sign = 1;
            
                    %Get diagonal reflection values
                    x2 = -y;
                    y2 = -x;
                    SideOffset = 0;
                  end
                  %Compute horizontal distance between current points
                  Distance = norm([x, y] - [x2, y2])/sqrt(2);
                  %Compute ratio for adapting to frequency limit changes
                  xyLimRatio = str2double(app.XUpperLimit.Value)/20;
            
                  %Prepare datatip
                  SideOffset = SideOffset*xyLimRatio*11;
                  dim = [AxesXLim(1)+AxesValuesXSpan*0.62 AxesYLim(1) + AxesValuesYSpan*0.02 AxesValuesXSpan*0.37 AxesValuesYSpan*0.17];
                  str = sprintf('\\nu_1 = %.2f MHz \\nu_2 = %.2f MHz',x,y);
                  str2 = sprintf('\\Delta_\\nu = %.2f MHz',Distance);
                  RectangleHandle = rectangle(app.mainPlot,'Position',dim,'FaceColor','r');
                  AnnotationHandle = text(app.mainPlot,AxesXLim(1)+AxesValuesXSpan*0.63,AxesYLim(1) + AxesValuesYSpan*0.13,str,'FontSize',13,'Color','w','FontWeight','bold');
                  AnnotationHandle2 = text(app.mainPlot,AxesXLim(1)+AxesValuesXSpan*0.63, AxesYLim(1) + AxesValuesYSpan*0.07,str2,'FontSize',13,'Color','w','FontWeight','bold');
            
                  %Plot line and points
                  Xaxis = app.Processed.axis1;
                  if x>0
                    Xaxis = Xaxis(Xaxis>0);
                    Slope = -1;
                  else
                    Xaxis = Xaxis(Xaxis<0);
                    Slope = 1;
                  end
                  Yaxis =  y + Slope*(Xaxis - (x));
                  hold(app.mainPlot,'on');
                  LineHandle = plot(app.mainPlot,Xaxis,Yaxis,'r','LineWidth',1.5);
                  PointHandle = plot(app.mainPlot,x,y,'.r',x2,y2,'.r','MarkerSize',30);
                  hold(app.mainPlot,'off');
                  %Force GUI update
                  drawnow;
                end
                %Update handles structure
                % guidata(hObject, handles);
            
                %Check if user has toogled off the button
                if ~event.Value
                  %If so delete all graphics handles and return
                  delete(AnnotationHandle)
                  if exist('AnnotationHandle2','var')
                    delete(AnnotationHandle2)
                  end
                  if exist('PointHandle2','var')
                    delete(PointHandle2)
                  end
                  if exist('PointHandle3','var')
                    delete(PointHandle3)
                  end
                  if exist('BoundLine','var')
                    delete(BoundLine)
                  end
                  delete(LineHandle)
                  delete(PointHandle)
                  delete(RectangleHandle)
                  break
                end
              end
            end
            
            return
        end

        % Button pushed function: GraphicalSettingsButton
        function GraphicalSettingsButton_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            setappdata(0,'GraphicalSettings',app.GraphicalSettings)
            %Make the window appear relative to the Hyscorean window
            Position = handles.HyscoreanFigure.Position;
            ScreenSize = get(0,'ScreenSize');
            Position(1) = Position(1)/ScreenSize(3) + 0.2;
            Position(2) = Position(2)/ScreenSize(4) + 0.2;
            Position(3) = 451.0/ScreenSize(3);
            Position(4) = 177.0/ScreenSize(4);
            %Call graphical settings GUI
            Hyscorean_GraphicalSettings('Units','normalized','Position',Position)
            uiwait(Hyscorean_GraphicalSettings)
            
            app.GraphicalSettings = getappdata(0,'GraphicalSettings');
            
            switch app.GraphicalSettings.Colormap
              case 1
                app.GraphicalSettings.ColormapName = 'parula';
              case 2
                app.GraphicalSettings.ColormapName = 'jet';
              case 3
                app.GraphicalSettings.ColormapName = 'hsv';
              case 4
                app.GraphicalSettings.ColormapName = 'hot';
              case 5
                app.GraphicalSettings.ColormapName = 'cool';
              case 6
                app.GraphicalSettings.ColormapName = 'spring';
              case 7
                app.GraphicalSettings.ColormapName = 'summer';
              case 8
                app.GraphicalSettings.ColormapName = 'autumn';
              case 9
                app.GraphicalSettings.ColormapName = 'winter';
              case 10
                app.GraphicalSettings.ColormapName = 'gray';
            end
            
            set(handles.ProcessingInfo, 'String', 'Status: Rendering...');drawnow;
            try
            [handles] = updateHyscoreanGUI(handles,handles.Processed)
            catch
            end
            set(handles.ProcessingInfo, 'String', 'Status: Finished');drawnow;
            
            guidata(hObject, handles);
            return
        end

        % Value changed function: ImaginaryTrace
        function ImaginaryTrace_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            if event.Value
              app.PlotImaginarySignal = true;
            else
              app.PlotImaginarySignal = false;
            end
            HyscoreanSignalPlot(app,app.Processed)
            % guidata(hObject, handles);
            return
        end

        % Button pushed function: LoadButton
        function LoadButton_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Inform the user that loading is under progress
            app.LoadedData.Text = 'Loading...'; drawnow;
            
            %Ask the user to load the files via OS window
            [app.FileNames, app.FilePaths, CancelFlag] = multiload_mod;
            
            %If laoding canceled just break the function
            if CancelFlag
              app.LoadedData.Text = 'Loading canceled'; drawnow;
              return;
            else
              app.DisplayLoadedFiles.Enable = true;
            end
            
            %If data has been reloaded and there exists and old processed spectrum,
            %remove all the associated data
            if ~isempty(app.Processed)
              app.Processed = [];
            end
            
            %Reset/Disable graphical handles so that no errors appear if called
            app.PreProcessedTrace.Visible = 'off';
            app.ImaginaryTrace.Visible = 'off';
            app.NonCorrectedTrace.Visible = 'off';
            app.PlotApodizationWindow.Visible = 'off';
            app.DetachSignalPlot.Visible = 'off';
            app.ChangeSignalPlotDimension.Visible = 'off';
            app.t1_Slider.Enable = 'off';
            app.ImposeBlindSpots.Enable = 'off';
            app.EasyspinFitButton.Enable = 'off';
            app.AddHelpLine.Enable = 'off';
            app.TransititonType.Enable = 'off';
            app.Validation_Button.Enable = 'off';
            app.AddTagList.Enable = 'off';
            app.ClearTags.Enable = 'off';
            app.FieldOffsetTag.Enable = 'off';
            app.GPS_button.Visible = 'off';
            app.ZoomButton.Visible = 'off';
            app.ZoomOutButton.Visible = 'off';
            app.ProcessButton.Enable = 'off';
            app.SaveReportButton.Enable = 'off';
            app.FieldOffset.Enable = 'off';
            set(findall(app.GraphicsPanel, '-property', 'Enable'), 'Enable', 'off')
            app.trace2Info.Text = '';
            app.TauSelectionSwitch = true;
            app.backgroundCorrectionSwitch = true;
            app.ReconstructionSwitch  = true;
            app.MountDataSwitch  = true;
            app.TauSelectionLamp.Color = app.gray_color;
            app.BackgroundCorrectionLamp.Color = app.gray_color;
            app.ReconstructionLamp.Color = app.gray_color;
            app.ReconstructionLamp.Visible = 'off';
            enableDisableGUI(app,'NUSReconstruction','off');
            drawnow;
            
            %Inform the user of how many files  have been loaded
            app.LoadedData.Text = sprintf('%d File(s) Loaded',length(app.FileNames)); drawnow;
            %Reset all plots to its startup form and inform
            resetPlots(app) %, handles);
            
            %Remove data of auxiliary tags and lines if exist
            if ~isempty(app.AddedLines)
              app.AddedLines = {};
             end
             if ~isempty(app.AddedTags)
               app.AddedTags = {};
             end
            
            %Mount the data
            try
              app.Data = mountHYSCOREdata(app.FileNames, app);
            catch Error
              %If something fails then inform the user and return
              uialert(app.HyscoreanFigure, sprintf('Loading failed due to errors: \n\n %s ',Error.message),'Error','Icon','error');
              app.LoadedData.Text = 'Loading failed';
              app.ProcessingInfo.Text = 'Status: Loading failed';
              drawnow;
              return
            end
            
            %Get the tau values found during the mounting
            TauValues = app.Data.TauValues;
            
            %Get all possible combinations
            [app.Selections, app.Data.Combinations] = getTauCombinations(TauValues);
            
            %Set the combination to the corresponding UI element
            app.MultiTauDimensions.Enable = 'on';
            app.MultiTauDimensions.Items = app.Selections;
            app.MultiTauDimensions.Value = app.Selections{1};
            
            %Set Freq. Axis Limit
            XUpperLimit_dt = app.Data.TimeStep1;
            if XUpperLimit_dt ~= 0
                app.XUpperLimit.Value = string(round(1/(2*XUpperLimit_dt)/10)*10); 
            end
            
            %Set the edit boxes depending on the signal size to the corresponding value
            app.ZeroFilling1.Value = string(size(app.Data.TauSignals,2));
            app.ZeroFilling2.Value = string(size(app.Data.TauSignals,3));
            app.ZeroFilling1_Text.Text = sprintf('t1: %i  +',size(app.Data.TauSignals,2));
            app.ZeroFilling2_Text.Text = sprintf('t2: %i  +',size(app.Data.TauSignals,3));
            app.WindowLength1.Value = string(size(app.Data.TauSignals,2));
            app.WindowLength2.Value = string(size(app.Data.TauSignals,3));
            
            %Check if data is NUS and activate the panels in the GUI
            if app.Data.NUSflag
              enableDisableGUI(app,'NUSReconstruction','on')
            end
            
            %Enable the process button and inform the user
            app.ProcessButton.Enable = 'on';
            app.ProcessingInfo.Text = 'Status: Ready';
            app.TauSelectionLamp.Color = app.amber_color;
            app.BackgroundCorrectionLamp.Color = app.amber_color;
            drawnow;
            
            % Save the handles structure.
            % guidata(hObject,handles)
            
            return
        end

        % Button pushed function: LoadSettings
        function LoadSettings_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            loadSettingsHyscorean(app);
            % guidata(hObject, handles);
            return
        end

        % Value changed function: Lorentz2GaussCheck
        function Lorentz2GaussCheck_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % if get(hObject,'Value')
            if app.Lorentz2GaussCheck.Value
                  enableDisableGUI(app,'Lorent2Gauss','on')
            else
                  enableDisableGUI(app,'Lorent2Gauss','off')
            end
            return
        end

        % Value changed function: MaxEntBackgroundParameter
        function MaxEntBackgroundParameter_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.ReconstructionSwitch = true;
            app.ReconstructionLamp.Color = app.gray_color;
            guidata(hObject, handles);
            return
        end

        % Value changed function: MaxEntLagrangianMultiplier
        function MaxEntLagrangianMultiplier_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.ReconstructionSwitch = true;
            app.ReconstructionLamp.Color = app.gray_color;
            guidata(hObject, handles);
            return
        end

        % Value changed function: MaximalContourLevel
        function MaximalContourLevel_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if str2double(app.MaximalContourLevel.Value) > 100
                app.MaximalContourLevel.Value = '100';
            end
            if str2double(app.MaximalContourLevel.Value) <= str2double(app.MinimalContourLevel.Value)
                app.MaximalContourLevel.Value = num2str(str2double(app.MinimalContourLevel.Value+0.5));
            end
            updateHyscoreanGUI(app,app.Processed);
            % guidata(hObject, handles);
            return
        end

        % Value changed function: MinimalContourLevel
        function MinimalContourLevel_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if str2double(app.MinimalContourLevel.Value) < 0
                app.MinimalContourLevel.Value = '0';
            end
            if str2double(app.MinimalContourLevel.Value) >= str2double(app.MaximalContourLevel.Value)
                app.MinimalContourLevel.Value = num2str(str2double(app.MaximalContourLevel.Value-0.5));
            end
            updateHyscoreanGUI(app,app.Processed);
            % guidata(hObject, handles);
            return
        end

        % Value changed function: MultiTauDimensions
        function MultiTauDimensions_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            app.TauSelectionSwitch = true;
            app.backgroundCorrectionSwitch = true;
            app.ReconstructionSwitch = true;
            app.TauSelectionLamp.Color = app.gray_color;
            app.BackgroundCorrectionLamp.Color = app.gray_color;
            app.ReconstructionLamp.Color = app.gray_color;
            app.Validation_Button.Enable = 'off';
            % guidata(hObject,handles)
            return
        end

        % Button pushed function: ProcessButton
        function ProcessButton_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Check if data is loaded (should always be like that just in case)
            if isempty(app.Data)
                app.ProcessingInfo.Text = 'Error: No data loaded.';
                return
            end
            
            %Launch the HYSCORE processing and update the GUI with the results
            try
            app.ProcessingInfo.Text = 'Status: Processing...'; drawnow;
            processHYSCORE(app);
            Npoints = length(app.Processed.TimeAxis2) - str2double(app.ZeroFilling2.Value);
            app.t1_Slider.Limits = [1 Npoints];
            app.t1_Slider.Step = 1/(Npoints - 1);
            app.t1_Slider.Value = 1;

            updateHyscoreanGUI(app,app.Processed);
            app.ProcessingInfo.Text = 'Ready';
            catch Error
              %Should some error occur inform the user and return
              w = errordlg(sprintf('The processing stopped due to an error : \n %s \n Please check your input. If this error persists restart the program.',Error.message),'Error','modal');
              waitfor(w);
              app.ProcessingInfo.Text = 'Error';
              return
            end
            
            %Enable the post-processing UI elements
            app.ImposeBlindSpots.Enable = 'on';
            app.AddHelpLine.Enable = 'on';
            app.TransititonType.Enable = 'on';
            app.AddTagList.Enable = 'on';
            app.ClearTags.Enable = 'on';
            app.FieldOffsetTag.Enable = 'on';
            app.FieldOffset.Enable = 'on';
            app.ZoomButton.Visible = 'on';
            app.GPS_button.Visible = 'on';
            app.ZoomOutButton.Visible = 'on';
            app.Validation_Button.Enable = 'on';
            %Enable the Fitting module only if EasySpin is installed
            if getpref('hyscorean','easyspin_installed')
                app.EasyspinFitButton.Enable = 'on';
            end
            set(findall(app.GraphicsPanel, '-property', 'Enable'), 'Enable', 'on');
            app.SaveReportButton.Enable = 'on';
            
            % guidata(hObject, handles)
            return
        end

        % Value changed function: ReconstructionAlgorithm
        function ReconstructionAlgorithm_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            handles.ReconstructionSwitch = true;
            app.ReconstructionLamp.Color = app.gray_color;
                switch get(hObject,'Value')
                  case 1
                    set(handles.MaxEntLagrangianMultiplier,'enable','on')
                    set(handles.LagrangeMultiplierText,'enable','on')
                    set(handles.BackgroundParameterText,'enable','on')
                    set(handles.MaxEntBackgroundParameter,'enable','on')
                  case {2,3,4}
                    set(handles.MaxEntLagrangianMultiplier,'enable','off')
                    set(handles.LagrangeMultiplierText,'enable','off')
                    set(handles.BackgroundParameterText,'enable','on')
                    set(handles.MaxEntBackgroundParameter,'enable','on')
                  otherwise
                    set(handles.MaxEntLagrangianMultiplier,'enable','off')
                    set(handles.LagrangeMultiplierText,'enable','off')
                    set(handles.BackgroundParameterText,'enable','off')
                    set(handles.MaxEntBackgroundParameter,'enable','off')
                end
            guidata(hObject, handles);
            return
        end

        % Button pushed function: SaveReportButton
        function SaveReportButton_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %If there is data to be saved then launch the save and report protocol
            if isempty(app.Processed)
              Window = warndlg('There is no processed data to be saved','Warning');
              return
            end
            saveHyscorean(app);
            return
        end

        % Button pushed function: SaveSettingsButton
        function SaveSettingsButton_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            saveSettings(app)
            return
        end

        % Button pushed function: SaverSettings
        function SaverSettings_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % setappdata(0,'SaverSettings',app.SaveHyscoreanSettings)
            % %Make the window appear relative to the Hyscorean window
            % Position = app.HyscoreanFigure.Position;
            % ScreenSize = get(0,'ScreenSize');
            % Position(1) = Position(1)/ScreenSize(3) + 0.2;
            % Position(2) = Position(2)/ScreenSize(4) + 0.2;
            % Position(3) = 451.0/ScreenSize(3);
            % Position(4) = 177.0/ScreenSize(4);
            % %Call saver settings GUI
            % Hyscorean_saveSettings('Units','normalized','Position',Position)
            % uiwait(Hyscorean_saveSettings)
            % app.SaveHyscoreanSettings = getappdata(0,'SaverSettings');
            % % guidata(hObject, handles);

            % Open save-settings dialog near the main Hyscorean window,
            % wait for user input, and read back the edited settings.
        
            % Position dialog relative to main window
            mainPos = app.HyscoreanFigure.Position;   % [left bottom width height]
            dlgLeft   = mainPos(1) + 40;
            dlgBottom = mainPos(2) + 40;
            dlgWidth  = 451;
            dlgHeight = 177;
        
            dlgPos = [dlgLeft dlgBottom dlgWidth dlgHeight];
        
            % Create dialog app and pass current settings
            dlg = Hyscorean_saveSettings(app.SaveHyscoreanSettings, dlgPos);
        
            % Wait until dialog closes or resumes
            uiwait(dlg.UIFigure);
        
            % If dialog still exists, read back accepted settings
            if isvalid(dlg) && dlg.Accepted
                    app.SaveHyscoreanSettings = dlg.SaverSettings;
            end
            if isvalid(dlg)
                delete(dlg);
            end
            return
        end

        % Value changed function: Symmetrization_ListBox
        function Symmetrization_ListBox_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % switch get(hObject,'Value')
            %   case 1
            %     handles.SymmetrizationString = 'None';
            %   case 2
            %     handles.SymmetrizationString = 'Diagonal';
            %   case 3
            %     handles.SymmetrizationString = 'Anti-Diagonal';
            %   case 4
            %     handles.SymmetrizationString = 'Both';
            % end
            app.SymmetrizationString = event.Value;
            app.ProcessingInfo.Text = 'Par. changed'; drawnow;
            % guidata(hObject, handles);
            return
        end

        % Value changed function: TransititonType
        function TransititonType_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            switch event.Value
              case 'SQC'
                app.FrequencyMultiplier = 1;
              case 'DQC'
                app.FrequencyMultiplier = 2;
              case 'Double DQC'
                app.FrequencyMultiplier = 4;
            end
            % app.FrequencyMultiplier = FrequencyMultiplier;
            % guidata(hObject, handles);
            return
        end

        % Button pushed function: Validation_Button
        function Validation_Button_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            %Get the signal before the pre-processing
            RawData.Signal = handles.Data.Integral;
            RawData.TimeAxis1 = handles.Data.TimeAxis1;
            RawData.TimeAxis2 = handles.Data.TimeAxis2;
            RawData.NUSflag = handles.Data.NUSflag;
            %Check if NUS reconstruction is needed
              switch get(handles.ReconstructionAlgorithm,'Value')
                case 1 %Constant-lambda CAMERA Reconstruction
                  ReconstructionMethod = 'constantcamera';
                case 2 %CAMERA
                  ReconstructionMethod = 'camera';
                case 3 %FFM-CG
                  ReconstructionMethod = 'ffmcg';
                case 4 %FFM-GD
                  ReconstructionMethod = 'ffmgd';
                case 5 %IST-S Reconstruction
                  ReconstructionMethod = 'ists';
                case 6 %IST-D Reconstruction
                  ReconstructionMethod = 'istd';
              end
            if RawData.NUSflag
              RawData.NUSgrid = handles.Data.NUSgrid;
              RawData.NUS = handles.Data.NUS;
            end
            
            %Get the current pre-processing settings
            Defaults.InvertCorrection = get(handles.InvertCorrection,'value');
            Defaults.BackgroundDimension1 = str2double(get(handles.BackgroundParameter1,'string'));
            Defaults.BackgroundDimension2 = str2double(get(handles.BackgroundParameter2,'string'));
            Defaults.BackgroundMethod1 = get(handles.BackgroundMethod1,'value') - 1;
            Defaults.BackgroundMethod2 = get(handles.BackgroundMethod2,'value') - 1;
            Defaults.BackgroundStart1 = str2double(get(handles.BackgroundStart1,'string'));
            Defaults.BackgroundStart2 = str2double(get(handles.BackgroundStart2,'string'));
            Defaults.BackgroundParameter = str2double(get(handles.MaxEntBackgroundParameter,'string'));
            Defaults.LagrangeMultiplier = str2double(get(handles.MaxEntLagrangianMultiplier,'string'));
            Defaults.ThresholdParameter = 0.98;
            Defaults.ReconstructionMethod = ReconstructionMethod;
            
            %Get the current processing settings
            Defaults.ZeroFilling1 = str2double(get(handles.ZeroFilling1,'string'));
            Defaults.ZeroFilling2 = str2double(get(handles.ZeroFilling2,'string'));
            Defaults.WindowType = handles.WindowTypeString;
            Defaults.WindowDecay1 = str2double(get(handles.WindowLength1,'string'));
            Defaults.WindowDecay2 = str2double(get(handles.WindowLength2,'string'));
            Defaults.SymmetrizationString = handles.SymmetrizationString;
            Parameters.tauFactor2 = str2double(get(handles.L2G_tau2,'string'));
            Parameters.sigmaFactor2 = str2double(get(handles.L2G_sigma2,'string'));
            Parameters.tauFactor1 = str2double(get(handles.L2G_tau,'string'));
            Parameters.sigmaFactor1 = str2double(get(handles.L2G_sigma,'string'));
            Defaults.L2GParameters = Parameters;
            Defaults.L2GCheck = get(handles.Lorentz2GaussCheck,'Value');
            
            Defaults.MaximalContourLevel = str2double(get(handles.MaximalContourLevel,'string'));
            Defaults.MinimalContourLevel = str2double(get(handles.MinimalContourLevel,'string'));
            Defaults.XUpperLimit = str2double(get(handles.XUpperLimit,'string'));
            GraphicalSetttings = getpref('hyscorean','graphicalsettings');
            Defaults.Levels = GraphicalSetttings.Levels;
            %Launch the validation module
            Hyscorean_validationModule(RawData,Defaults)
            
            return
        end

        % Value changed function: WindowType
        function WindowType_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                WindowMenuState = app.WindowType.Value;
              switch WindowMenuState
                case 'Hamming'
                 WinType =  'hamming';
                case 'Chebyshev'
                 WinType =  'chebyshev';
                case 'Welch'
                 WinType =  'welch';
                case 'Blackman'
                  WinType = 'blackman';
                case 'Bartlett'
                  WinType = 'bartlett';
                case 'Connes'
                  WinType = 'connes';
                case 'Cosine'
                  WinType = 'cosine';
                case '25% Tukey'
                  WinType = 'tukey25';
                case '50% Tukey'
                  WinType = 'tukey50';
                case '75% Tukey'
                  WinType = 'tukey75';
                case 'Hann'
                  WinType = 'hann';
                case 'None'
                  WinType = 'none';
              end
              if strcmp(WindowMenuState, 'none')
                app.WindowLength1.Enable = 'off';
                app.WindowLength2.Enable = 'off';
                app.WindowLengthText1.Enable = 'off';
                app.WindowLengthText2.Enable = 'off';
              else
                app.WindowLength1.Enable = 'on';
                app.WindowLength2.Enable = 'on';
                app.WindowLengthText1.Enable = 'on';
                app.WindowLengthText2.Enable = 'on';
              end
            app.WindowTypeString = WinType;
            app.ProcessingInfo.Text = 'Par. changed'; drawnow;
            % guidata(hObject, handles);
            return
        end

        % Value changed function: XUpperLimit
        function XUpperLimit_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            updateHyscoreanGUI(app,app.Processed)
            % guidata(hObject, handles);
            return
        end

        % Button pushed function: ZoomButton
        function ZoomButton_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            z = zoom;
            z.ActionPostCallback = {@app.zoompostcallback,event,app};
            z.Enable = 'On';
            return
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButton_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            z.Enable = 'Off';
            Upperlimit = str2double(app.XUpperLimit.Value);
            set(app.mainPlot,'xlim',[-Upperlimit Upperlimit],'ylim',[0 Upperlimit])
            return
        end

        % Button pushed function: detachMainContour
        function detachMainContour_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %Find figure, close it and open it again
            Figure = findobj('Tag','mainContourDetached');
            if isempty(Figure)
              Figure = figure('Tag','mainContourDetached','WindowStyle','normal');
            else
              figure(Figure);
              clf(Figure);
            end
            %Make the window appear relative to the Hyscorean window
            ScreenSize = get(0,'ScreenSize');
            Position = handles.HyscoreanFigure.Position;
            Position(1) = Position(1)/ScreenSize(3)+0.1;
            Position(2) = Position(2)/ScreenSize(4);
            %Copy object as it is
            AxesHandles = copyobj(handles.mainPlot,Figure);
            GraphicalSettings = getpref('hyscorean','graphicalsettings');
            set(Figure,'NumberTitle','off','Name','Hyscorean: HYSCORE spectrum',...
              'Units','normalized','Position',[Position(1) Position(2) 776/ScreenSize(3) 415/ScreenSize(4)]);
            set(AxesHandles,'Position',[0.07 0.12 0.9 0.85]);
            colormap(AxesHandles,GraphicalSettings.ColormapName)
            
            %Use Hyscorean window logo
            setFigureIcon(Figure);
            
            %If the blindspots are being superimposed then switch to the hot colormap
            if app.ImposeBlindSpots.Value
              colormap(AxesHandles,'hot')
            end
            
            return
        end

        % Button pushed function: detachMainSurface
        function detachMainSurface_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %Find figure, close it and open it again
            Figure = findobj('Tag','mainSurfaceDetached');
            if isempty(Figure)
              Figure = figure('Tag','mainSurfaceDetached','WindowStyle','normal');
            else
              figure(Figure);
              clf(Figure);
            end
            %Use Hyscorean window logo
            setFigureIcon(Figure);
            
            %Make the window appear relative to the Hyscorean window
            ScreenSize = get(0,'ScreenSize');
            Position = handles.HyscoreanFigure.Position;
            Position(1) = Position(1)/ScreenSize(3)+0.1;
            Position(2) = Position(2)/ScreenSize(4);
            ScreenSize = get(0,'ScreenSize');
            set(Figure,'NumberTitle','off','Name','Hyscorean: HYSCORE Surface',...
                'Units','normalized','Position',[Position(1) Position(2) 776/ScreenSize(3) 415/ScreenSize(4)]);
            if app.GraphicalSettings.Absolute
              spectrum2 = abs(handles.Processed.spectrum);
            elseif app.GraphicalSettings.Real
              spectrum2 = abs(handles.Processed.spectrum);
            elseif app.GraphicalSettings.Imaginary
              spectrum2 = imag(handles.Processed.spectrum);
            end
            surf(handles.Processed.axis1,handles.Processed.axis2,spectrum2)
            colormap(app.GraphicalSettings.ColormapName)
            shading('flat'),colorbar
            XupperLimit = str2double(get(handles.XUpperLimit,'string'));
            xlim([-XupperLimit XupperLimit]),ylim([0 XupperLimit])
            xlabel('\nu_1 [MHz]'), ylabel('\nu_2 [MHz]')
            return
        end

        % Button pushed function: plotNUSgrid
        function plotNUSgrid_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            displayNUSreconstructionResults(handles)
            return
        end

        % Value changed function: t1_Slider
        function t1_Slider_Callback(app, event)
            %==========================================================================
            
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % app.t1_Slider.Value = event.Value;
            app.PlotProcessedSignal = true;
            HyscoreanSignalPlot(app,app.Processed)
            % guidata(hObject,handles)
            return
        end

        % Value changed function: InvertCorrection
        function InvertCorrection_Callback(app, event)
            app.backgroundCorrectionSwitch = true;
            app.ReconstructionSwitch  = true;
            app.BackgroundCorrectionLamp.Color = app.amber_color;
            app.ReconstructionLamp.Color = app.amber_color;    
        end

        % Value changed function: ImposeBlindSpots
        function ImposeBlindSpotsValueChanged(app, event)
            updateHyscoreanGUI(app,app.Processed)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HyscoreanFigure = uifigure('Visible', 'off');
            app.HyscoreanFigure.Name = 'Hyscorean';
            app.HyscoreanFigure.Tag = 'HyscoreanFigure';
            app.mainPlot = uiaxes(app.HyscoreanFigure);
            app.mainPlot.XTick = zeros(1,0);
            app.mainPlot.YTick = zeros(1,0);
            app.mainPlot.Tag = 'mainPlot';
            app.signal_t1 = uiaxes(app.HyscoreanFigure);
            app.signal_t1.XTick = [];
            app.signal_t1.XTickLabel = {};
            app.signal_t1.YTick = [];
            app.signal_t1.Tag = 'signal_t1';
            app.uipanel8 = uipanel(app.HyscoreanFigure);
            app.uipanel8.Title = 'Processing';
            app.uipanel8.Tag = 'uipanel8';
            app.uipanel9 = uipanel(app.uipanel8);
            app.uipanel9.Tag = 'uipanel9';
            app.L2GSigmaText = uilabel(app.uipanel8);
            app.L2GSigmaText.Tag = 'L2GSigmaText';
            app.L2GSigmaText.Enable = 'off';
            app.L2GSigmaText.Text = 'Sigma 1';
            app.L2GTauText = uilabel(app.uipanel8);
            app.L2GTauText.Tag = 'L2GTauText';
            app.L2GTauText.Enable = 'off';
            app.L2GTauText.Text = 'Tau 1';
            app.text60 = uilabel(app.uipanel8);
            app.text60.Tag = 'text60';
            app.text60.Text = 'Apodization';
            app.text61 = uilabel(app.uipanel8);
            app.text61.Tag = 'text61';
            app.text61.Text = 'Zero-Filling (points)';
            app.ZeroFilling1 = uieditfield(app.uipanel8, 'text');
            app.ZeroFilling1.Tag = 'ZeroFilling1';
            app.ZeroFilling1.Value = '0';
            app.ZeroFilling1_Text = uilabel(app.uipanel8);
            app.ZeroFilling1_Text.Tag = 'ZeroFilling1_Text';
            app.ZeroFilling1_Text.Text = 't1: 0  + ';
            app.ZeroFilling2 = uieditfield(app.uipanel8, 'text');
            app.ZeroFilling2.Tag = 'ZeroFilling2';
            app.ZeroFilling2.Value = '0';
            app.ZeroFilling2_Text = uilabel(app.uipanel8);
            app.ZeroFilling2_Text.Tag = 'ZeroFilling2_Text';
            app.ZeroFilling2_Text.Text = 't2: 0  + ';
            app.L2G_sigma = uieditfield(app.uipanel8, 'text');
            app.L2G_sigma.Tag = 'L2G_sigma';
            app.L2G_sigma.Enable = 'off';
            app.L2G_sigma.Value = '0.5';
            app.L2G_tau = uieditfield(app.uipanel8, 'text');
            app.L2G_tau.Tag = 'L2G_tau';
            app.L2G_tau.Enable = 'off';
            app.L2G_tau.Value = '0.05';
            app.L2GSigmaText2 = uilabel(app.uipanel8);
            app.L2GSigmaText2.Tag = 'L2GSigmaText2';
            app.L2GSigmaText2.Enable = 'off';
            app.L2GSigmaText2.Text = 'Sigma 2';
            app.L2GTauText2 = uilabel(app.uipanel8);
            app.L2GTauText2.Tag = 'L2GTauText2';
            app.L2GTauText2.Enable = 'off';
            app.L2GTauText2.Text = 'Tau 2';
            app.L2G_sigma2 = uieditfield(app.uipanel8, 'text');
            app.L2G_sigma2.Tag = 'L2G_sigma2';
            app.L2G_sigma2.Enable = 'off';
            app.L2G_sigma2.Value = '0.5';
            app.L2G_tau2 = uieditfield(app.uipanel8, 'text');
            app.L2G_tau2.Tag = 'L2G_tau2';
            app.L2G_tau2.Enable = 'off';
            app.L2G_tau2.Value = '0.05';
            app.ProcessButton = uibutton(app.uipanel8, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButton_Callback, true);
            app.ProcessButton.Tag = 'ProcessButton';
            app.ProcessButton.Enable = 'off';
            app.ProcessButton.Text = 'Process';
            app.ProcessingInfo = uilabel(app.uipanel8);
            app.ProcessingInfo.Tag = 'ProcessingInfo';
            app.ProcessingInfo.Text = '';
            app.Lorentz2GaussCheck = uicheckbox(app.uipanel8);
            app.Lorentz2GaussCheck.ValueChangedFcn = createCallbackFcn(app, @Lorentz2GaussCheck_Callback, true);
            app.Lorentz2GaussCheck.Tag = 'Lorentz2GaussCheck';
            app.Lorentz2GaussCheck.Tooltip = 'Activate/Deactivate L2G';
            app.Lorentz2GaussCheck.Text = 'Lorentz-to-Gauss Transformation';
            app.WindowType = uidropdown(app.uipanel8);
            app.WindowType.Items = {'Hamming', 'Chebyshev', 'Welch', 'Blackman', 'Bartlett', 'Connes', 'Cosine', '25% Tukey', '50% Tukey', '75% Tukey', 'Hann', 'None'};
            app.WindowType.ValueChangedFcn = createCallbackFcn(app, @WindowType_Callback, true);
            app.WindowType.Tag = 'WindowType';
            app.WindowType.Value = 'Chebyshev';
            app.SymmetrizationText = uilabel(app.uipanel8);
            app.SymmetrizationText.Tag = 'SymmetrizationText';
            app.SymmetrizationText.Text = 'Spectral Symmetrization';
            app.Symmetrization_ListBox = uidropdown(app.uipanel8);
            app.Symmetrization_ListBox.Items = {'None', 'Diagonal', 'Anti-Diagonal', 'Diagonal & Anti-Diagonal'};
            app.Symmetrization_ListBox.ValueChangedFcn = createCallbackFcn(app, @Symmetrization_ListBox_Callback, true);
            app.Symmetrization_ListBox.Tag = 'Symmetrization_ListBox';
            app.Symmetrization_ListBox.Value = 'None';
            app.WindowLengthText1 = uilabel(app.uipanel8);
            app.WindowLengthText1.Tag = 'WindowLengthText1';
            app.WindowLengthText1.Text = 'Window length 1';
            app.WindowLengthText2 = uilabel(app.uipanel8);
            app.WindowLengthText2.Tag = 'WindowLengthText2';
            app.WindowLengthText2.Text = 'Window length 2';
            app.WindowLength1 = uieditfield(app.uipanel8, 'text');
            app.WindowLength1.Tag = 'WindowLength1';
            app.WindowLength1.Tooltip = 'Number of points after which the window is at minimum  ';
            app.WindowLength1.Value = '200';
            app.WindowLength2 = uieditfield(app.uipanel8, 'text');
            app.WindowLength2.Tag = 'WindowLength2';
            app.WindowLength2.Tooltip = 'Number of points after which the window is at minimum  ';
            app.WindowLength2.Value = '200';
            app.GraphicsPanel = uipanel(app.HyscoreanFigure);
            app.GraphicsPanel.Title = 'Graphics';
            app.GraphicsPanel.Tag = 'GraphicsPanel';
            app.detachMainContour = uibutton(app.GraphicsPanel, 'push');
            app.detachMainContour.ButtonPushedFcn = createCallbackFcn(app, @detachMainContour_Callback, true);
            app.detachMainContour.Tag = 'detachMainContour';
            app.detachMainContour.Icon = 'detachMainContour_image.png';
            app.detachMainContour.Enable = 'off';
            app.detachMainContour.Tooltip = 'Detach spectrum as contour graph';
            app.detachMainContour.Text = '';
            app.text39 = uilabel(app.GraphicsPanel);
            app.text39.Tag = 'text39';
            app.text39.Enable = 'off';
            app.text39.Text = 'Contour';
            app.detachMainSurface = uibutton(app.GraphicsPanel, 'push');
            app.detachMainSurface.ButtonPushedFcn = createCallbackFcn(app, @detachMainSurface_Callback, true);
            app.detachMainSurface.Tag = 'detachMainSurface';
            app.detachMainSurface.Icon = 'detachMainSurface_image.png';
            app.detachMainSurface.Enable = 'off';
            app.detachMainSurface.Tooltip = 'Detach spectrum as surface graph';
            app.detachMainSurface.Text = '';
            app.text40 = uilabel(app.GraphicsPanel);
            app.text40.Tag = 'text40';
            app.text40.Enable = 'off';
            app.text40.Text = 'Surface';
            app.GraphicalSettingsButton = uibutton(app.GraphicsPanel, 'push');
            app.GraphicalSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @GraphicalSettingsButton_Callback, true);
            app.GraphicalSettingsButton.Tag = 'GraphicalSettingsButton';
            app.GraphicalSettingsButton.Enable = 'off';
            app.GraphicalSettingsButton.Tooltip = 'Open graphical settings window';
            app.GraphicalSettingsButton.Text = 'Graphical Settings';
            app.text71 = uilabel(app.GraphicsPanel);
            app.text71.Tag = 'text71';
            app.text71.Enable = 'off';
            app.text71.Text = 'Axis Frequency Limit';
            app.XUpperLimit = uieditfield(app.GraphicsPanel, 'text');
            app.XUpperLimit.ValueChangedFcn = createCallbackFcn(app, @XUpperLimit_Callback, true);
            app.XUpperLimit.Tag = 'XUpperLimit';
            app.XUpperLimit.Enable = 'off';
            app.XUpperLimit.Value = '20';
            app.text78 = uilabel(app.GraphicsPanel);
            app.text78.Tag = 'text78';
            app.text78.Enable = 'off';
            app.text78.Text = 'MHz';
            app.text79 = uilabel(app.GraphicsPanel);
            app.text79.Tag = 'text79';
            app.text79.Enable = 'off';
            app.text79.Text = 'Min-Max Contour Level';
            app.MinimalContourLevel = uieditfield(app.GraphicsPanel, 'text');
            app.MinimalContourLevel.ValueChangedFcn = createCallbackFcn(app, @MinimalContourLevel_Callback, true);
            app.MinimalContourLevel.Tag = 'MinimalContourLevel';
            app.MinimalContourLevel.Enable = 'off';
            app.MinimalContourLevel.Value = '0.5';
            app.text80 = uilabel(app.GraphicsPanel);
            app.text80.Tag = 'text80';
            app.text80.Enable = 'off';
            app.text80.Text = '%';
            app.DetachProjectionPlot = uibutton(app.GraphicsPanel, 'push');
            app.DetachProjectionPlot.ButtonPushedFcn = createCallbackFcn(app, @DetachProjectionPlot_Callback, true);
            app.DetachProjectionPlot.Tag = 'DetachProjectionPlot';
            app.DetachProjectionPlot.Icon = 'DetachProjectionPlot_image.png';
            app.DetachProjectionPlot.Enable = 'off';
            app.DetachProjectionPlot.Tooltip = 'Detach spectrum as interactive projection graph';
            app.DetachProjectionPlot.Text = '';
            app.DetachProjectionPlotText = uilabel(app.GraphicsPanel);
            app.DetachProjectionPlotText.Tag = 'DetachProjectionPlotText';
            app.DetachProjectionPlotText.Enable = 'off';
            app.DetachProjectionPlotText.Text = 'Projection';
            app.MaximalContourLevel = uieditfield(app.GraphicsPanel, 'text');
            app.MaximalContourLevel.ValueChangedFcn = createCallbackFcn(app, @MaximalContourLevel_Callback, true);
            app.MaximalContourLevel.Tag = 'MaximalContourLevel';
            app.MaximalContourLevel.Enable = 'off';
            app.MaximalContourLevel.Value = '100';
            app.text96 = uilabel(app.GraphicsPanel);
            app.text96.Tag = 'text96';
            app.text96.Enable = 'off';
            app.text96.Text = '%';
            app.uipanel6 = uipanel(app.HyscoreanFigure);
            app.uipanel6.Title = 'Pre-Processing';
            app.uipanel6.Tag = 'uipanel6';
            app.TauSelectionText = uilabel(app.uipanel6);
            app.TauSelectionText.Tag = 'TauSelectionText';
            app.TauSelectionText.Text = 'Tau-Selection';
            app.BackgroundMethod1 = uidropdown(app.uipanel6);
            app.BackgroundMethod1.Items = {'Fractal', 'n-Dimensional', 'Polynomial', 'Exponential'};
            app.BackgroundMethod1.ValueChangedFcn = createCallbackFcn(app, @BackgroundMethod1_Callback, true);
            app.BackgroundMethod1.Tag = 'BackgroundMethod1';
            app.BackgroundMethod1.Value = 'Polynomial';
            app.BackgroundMethod2 = uidropdown(app.uipanel6);
            app.BackgroundMethod2.Items = {'Fractal', 'n-Dimensional', 'Polynomial', 'Exponential'};
            app.BackgroundMethod2.ValueChangedFcn = createCallbackFcn(app, @BackgroundMethod2_Callback, true);
            app.BackgroundMethod2.Tag = 'BackgroundMethod2';
            app.BackgroundMethod2.Value = 'Polynomial';
            app.BackgroundParameter1 = uieditfield(app.uipanel6, 'text');
            app.BackgroundParameter1.ValueChangedFcn = createCallbackFcn(app, @BackgroundParameter1_Callback, true);
            app.BackgroundParameter1.Tag = 'BackgroundParameter1';
            app.BackgroundParameter1.Value = '1';
            app.BackgroundParameter2 = uieditfield(app.uipanel6, 'text');
            app.BackgroundParameter2.ValueChangedFcn = createCallbackFcn(app, @BackgroundParameter2_Callback, true);
            app.BackgroundParameter2.Tag = 'BackgroundParameter2';
            app.BackgroundParameter2.Value = '1';
            app.text46 = uilabel(app.uipanel6);
            app.text46.Tag = 'text46';
            app.text46.Text = '1st';
            app.text47 = uilabel(app.uipanel6);
            app.text47.Tag = 'text47';
            app.text47.Text = '2nd ';
            app.BackgroundParameterText1 = uilabel(app.uipanel6);
            app.BackgroundParameterText1.Tag = 'BackgroundParameterText1';
            app.BackgroundParameterText1.Text = 'Polynomial Order';
            app.BackgroundParameterText2 = uilabel(app.uipanel6);
            app.BackgroundParameterText2.Tag = 'BackgroundParameterText2';
            app.BackgroundParameterText2.Text = 'Polynomial Order';
            app.NUSReconstructionText = uilabel(app.uipanel6);
            app.NUSReconstructionText.Tag = 'NUSReconstructionText';
            app.NUSReconstructionText.Enable = 'off';
            app.NUSReconstructionText.Text = 'NUS Reconstruction';
            app.ReconstructionAlgorithm = uidropdown(app.uipanel6);
            app.ReconstructionAlgorithm.Items = {'constant-lambda CAMERA', 'constant-aim CAMERA', 'FFM-CG', 'FFM-GD', 'IST-S', 'IST-D'};
            app.ReconstructionAlgorithm.ValueChangedFcn = createCallbackFcn(app, @ReconstructionAlgorithm_Callback, true);
            app.ReconstructionAlgorithm.Tag = 'ReconstructionAlgorithm';
            app.ReconstructionAlgorithm.Enable = 'off';
            app.ReconstructionAlgorithm.Value = 'constant-lambda CAMERA';
            app.BackgroundParameterText = uilabel(app.uipanel6);
            app.BackgroundParameterText.Tag = 'BackgroundParameterText';
            app.BackgroundParameterText.Enable = 'off';
            app.BackgroundParameterText.Text = 'Background Param. (log10)';
            app.MaxEntBackgroundParameter = uieditfield(app.uipanel6, 'text');
            app.MaxEntBackgroundParameter.ValueChangedFcn = createCallbackFcn(app, @MaxEntBackgroundParameter_Callback, true);
            app.MaxEntBackgroundParameter.Tag = 'MaxEntBackgroundParameter';
            app.MaxEntBackgroundParameter.Enable = 'off';
            app.MaxEntBackgroundParameter.Value = '-5';
            app.LagrangeMultiplierText = uilabel(app.uipanel6);
            app.LagrangeMultiplierText.Tag = 'LagrangeMultiplierText';
            app.LagrangeMultiplierText.Enable = 'off';
            app.LagrangeMultiplierText.Text = 'Lagrangian Mult.';
            app.MaxEntLagrangianMultiplier = uieditfield(app.uipanel6, 'text');
            app.MaxEntLagrangianMultiplier.ValueChangedFcn = createCallbackFcn(app, @MaxEntLagrangianMultiplier_Callback, true);
            app.MaxEntLagrangianMultiplier.Tag = 'MaxEntLagrangianMultiplier';
            app.MaxEntLagrangianMultiplier.Enable = 'off';
            app.MaxEntLagrangianMultiplier.Value = '500';
            app.MultiTauDimensions = uidropdown(app.uipanel6);
            app.MultiTauDimensions.Items = {'', ''};
            app.MultiTauDimensions.ValueChangedFcn = createCallbackFcn(app, @MultiTauDimensions_Callback, true);
            app.MultiTauDimensions.Tag = 'MultiTauDimensions';
            app.MultiTauDimensions.Enable = 'off';
            app.MultiTauDimensions.Tooltip = 'Tau values of spectra to be combined';
            app.MultiTauDimensions.Value = '';
            app.text43 = uilabel(app.uipanel6);
            app.text43.Tag = 'text43';
            app.text43.Text = 'Background Correction';
            app.text82 = uilabel(app.uipanel6);
            app.text82.Tag = 'text82';
            app.text82.Text = ' Fit Start';
            app.BackgroundStart1 = uieditfield(app.uipanel6, 'text');
            app.BackgroundStart1.ValueChangedFcn = createCallbackFcn(app, @BackgroundStart1_Callback, true);
            app.BackgroundStart1.Tag = 'BackgroundStart1';
            app.BackgroundStart1.Value = '1';
            app.BackgroundStart2 = uieditfield(app.uipanel6, 'text');
            app.BackgroundStart2.ValueChangedFcn = createCallbackFcn(app, @BackgroundStart2_Callback, true);
            app.BackgroundStart2.Tag = 'BackgroundStart2';
            app.BackgroundStart2.Value = '1';
            app.plotNUSgridText = uilabel(app.uipanel6);
            app.plotNUSgridText.Tag = 'plotNUSgridText';
            app.plotNUSgridText.Enable = 'off';
            app.plotNUSgridText.Text = 'Overview';
            app.plotNUSgrid = uibutton(app.uipanel6, 'push');
            app.plotNUSgrid.ButtonPushedFcn = createCallbackFcn(app, @plotNUSgrid_Callback, true);
            app.plotNUSgrid.Tag = 'plotNUSgrid';
            app.plotNUSgrid.Icon = 'plotNUSgrid_image.png';
            app.plotNUSgrid.Enable = 'off';
            app.plotNUSgrid.Text = '';
            app.Validation_Button = uibutton(app.uipanel6, 'push');
            app.Validation_Button.ButtonPushedFcn = createCallbackFcn(app, @Validation_Button_Callback, true);
            app.Validation_Button.Tag = 'Validation_Button';
            app.Validation_Button.Enable = 'off';
            app.Validation_Button.Text = 'Validation';
            app.text94 = uilabel(app.uipanel6);
            app.text94.Tag = 'text94';
            app.text94.Text = 'points';
            app.text95 = uilabel(app.uipanel6);
            app.text95.Tag = 'text95';
            app.text95.Text = 'points';
            app.InvertCorrection = uicheckbox(app.uipanel6);
            app.InvertCorrection.ValueChangedFcn = createCallbackFcn(app, @InvertCorrection_Callback, true);
            app.InvertCorrection.Text = 'Invert Sequence';
            app.TauSelectionLamp = uilamp(app.uipanel6);
            app.BackgroundCorrectionLamp = uilamp(app.uipanel6);
            app.ReconstructionLamp = uilamp(app.uipanel6);
            app.ReconstructionLamp.Visible = 'off';
            app.trace2Info = uilabel(app.HyscoreanFigure);
            app.trace2Info.Tag = 'trace2Info';
            app.trace2Info.Text = 'Trace along t1 at t2 = 0 ns';
            app.LoadButton = uibutton(app.HyscoreanFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_Callback, true);
            app.LoadButton.Tag = 'LoadButton';
            app.LoadButton.Tooltip = {'Load files into Hyscorean'};
            app.LoadButton.Text = 'Load';
            app.LoadedData = uilabel(app.HyscoreanFigure);
            app.LoadedData.Tag = 'LoadedData';
            app.LoadedData.Text = 'No loaded data';
            app.t1_Slider = uislider(app.HyscoreanFigure);
            app.t1_Slider.Limits = [0 1];
            app.t1_Slider.MajorTicks = [];
            app.t1_Slider.ValueChangedFcn = createCallbackFcn(app, @t1_Slider_Callback, true);
            app.t1_Slider.MinorTicks = [0 0.05 0.1 0.15 0.2 0.25 0.3 0.35 0.4 0.45 0.5 0.55 0.6 0.65 0.7 0.75 0.8 0.85 0.9 0.95 1];
            app.t1_Slider.Step = 0.01;
            app.t1_Slider.Tag = 't1_Slider';
            app.t1_Slider.Enable = 'off';
            app.t1_Slider.Value = 1;
            app.SaveReportButton = uibutton(app.HyscoreanFigure, 'push');
            app.SaveReportButton.ButtonPushedFcn = createCallbackFcn(app, @SaveReportButton_Callback, true);
            app.SaveReportButton.Tag = 'SaveReportButton';
            app.SaveReportButton.Enable = 'off';
            app.SaveReportButton.Tooltip = 'Save data, settings and generate report';
            app.SaveReportButton.Text = 'Save & Report';
            app.DisplayLoadedFiles = uibutton(app.HyscoreanFigure, 'push');
            app.DisplayLoadedFiles.ButtonPushedFcn = createCallbackFcn(app, @DisplayLoadedFiles_Callback, true);
            app.DisplayLoadedFiles.Tag = 'DisplayLoadedFiles';
            app.DisplayLoadedFiles.Enable = 'off';
            app.DisplayLoadedFiles.Tooltip = 'Show list of loaded files';
            app.DisplayLoadedFiles.Text = '...';
            app.SaveSettingsButton = uibutton(app.HyscoreanFigure, 'push');
            app.SaveSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSettingsButton_Callback, true);
            app.SaveSettingsButton.Tag = 'SaveSettingsButton';
            app.SaveSettingsButton.Tooltip = 'Save settings to file';
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettings = uibutton(app.HyscoreanFigure, 'push');
            app.LoadSettings.ButtonPushedFcn = createCallbackFcn(app, @LoadSettings_Callback, true);
            app.LoadSettings.Tag = 'LoadSettings';
            app.LoadSettings.Tooltip = 'Load settings from file';
            app.LoadSettings.Text = 'Load Settings';
            app.SaverSettings = uibutton(app.HyscoreanFigure, 'push');
            app.SaverSettings.ButtonPushedFcn = createCallbackFcn(app, @SaverSettings_Callback, true);
            app.SaverSettings.Tag = 'SaverSettings';
            app.SaverSettings.Tooltip = 'Set save path and name';
            app.SaverSettings.Text = '...';
            app.text76 = uilabel(app.HyscoreanFigure);
            app.text76.Tag = 'text76';
            app.text76.Text = 'Hyscorean';
            app.text77 = uilabel(app.HyscoreanFigure);
            app.text77.Tag = 'text77';
            app.text77.Text = 'HYSCORE 1D-Time Traces';
            app.DetachSignalPlot = uibutton(app.HyscoreanFigure, 'push');
            app.DetachSignalPlot.ButtonPushedFcn = createCallbackFcn(app, @DetachSignalPlot_Callback, true);
            app.DetachSignalPlot.Tag = 'DetachSignalPlot';
            app.DetachSignalPlot.Icon = 'DetachSignalPlot_image.png';
            app.DetachSignalPlot.Visible = 'off';
            app.DetachSignalPlot.Tooltip = 'Detach signal trace graph as interactive window';
            app.DetachSignalPlot.Text = '';
            app.AddOnsPanel = uipanel(app.HyscoreanFigure);
            app.AddOnsPanel.Title = 'Add-Ons';
            app.AddOnsPanel.Tag = 'AddOnsPanel';
            app.AddHelpLine = uibutton(app.AddOnsPanel, 'push');
            app.AddHelpLine.ButtonPushedFcn = createCallbackFcn(app, @AddHelpLine_Callback, true);
            app.AddHelpLine.Tag = 'AddHelpLine';
            app.AddHelpLine.Enable = 'off';
            app.AddHelpLine.Text = 'Add Auxiliary Line';
            app.TransititonType = uidropdown(app.AddOnsPanel);
            app.TransititonType.Items = {'SQC', 'DQC', 'Double DQC'};
            app.TransititonType.ValueChangedFcn = createCallbackFcn(app, @TransititonType_Callback, true);
            app.TransititonType.Tag = 'TransititonType';
            app.TransititonType.Enable = 'off';
            app.TransititonType.Value = 'SQC';
            app.ClearTags = uibutton(app.AddOnsPanel, 'push');
            app.ClearTags.ButtonPushedFcn = createCallbackFcn(app, @ClearTags_Callback, true);
            app.ClearTags.Tag = 'ClearTags';
            app.ClearTags.Enable = 'off';
            app.ClearTags.Text = 'Clear Lines';
            app.FieldOffset = uieditfield(app.AddOnsPanel, 'text');
            app.FieldOffset.Tag = 'FieldOffset';
            app.FieldOffset.Enable = 'off';
            app.FieldOffset.Value = '0';
            app.FieldOffsetTag = uilabel(app.AddOnsPanel);
            app.FieldOffsetTag.Tag = 'FieldOffsetTag';
            app.FieldOffsetTag.Enable = 'off';
            app.FieldOffsetTag.Text = 'B-Field Offset (G)';
            app.BlindSpotsSimulator = uibutton(app.AddOnsPanel, 'push');
            app.BlindSpotsSimulator.ButtonPushedFcn = createCallbackFcn(app, @BlindSpotsSimulator_Callback, true);
            app.BlindSpotsSimulator.Tag = 'BlindSpotsSimulator';
            app.BlindSpotsSimulator.Tooltip = 'Blindspot simulator tool';
            app.BlindSpotsSimulator.Text = 'Simulator';
            app.AddTagList = uidropdown(app.AddOnsPanel);
            app.AddTagList.Items = {'1H', '2H', '3H', '3He', '6Li', '7Li', '9Be', '10B', '11B', '13C', '14N', '15N', '17O', '19F', '21Ne', '22Na', '23Na', '25Mg', '27Al', '29Si', '31P', '33S', '35Cl', '36Cl', '37Cl', '39Ar', '39K', '40K', '<HTML><FONT color=010101></FONT><SUP>41</SUP>K</HTML>', '<HTML><FONT color=010101></FONT><SUP>41</SUP>Ca</HTML>', '<HTML><FONT color=010101></FONT><SUP>43</SUP>Ca</HTML>', '<HTML><FONT color=010101></FONT><SUP>45</SUP>Sc</HTML>', '<HTML><FONT color=010101></FONT><SUP>47</SUP>Ti</HTML>', '<HTML><FONT color=010101></FONT><SUP>49</SUP>Ti</HTML>', '<HTML><FONT color=010101></FONT><SUP>50</SUP>V</HTML>', '<HTML><FONT color=010101></FONT><SUP>51</SUP>V</HTML>', '<HTML><FONT color=010101></FONT><SUP>53</SUP>Cr</HTML>', '<HTML><FONT color=010101></FONT><SUP>53</SUP>Mn</HTML>', '<HTML><FONT color=010101></FONT><SUP>55</SUP>Mn</HTML>', '<HTML><FONT color=010101></FONT><SUP>57</SUP>Fe</HTML>', '<HTML><FONT color=010101></FONT><SUP>59</SUP>Co</HTML>', '<HTML><FONT color=010101></FONT><SUP>60</SUP>Co</HTML>', '<HTML><FONT color=010101></FONT><SUP>61</SUP>Ni</HTML>', '<HTML><FONT color=010101></FONT><SUP>63</SUP>Cu</HTML>', '<HTML><FONT color=010101></FONT><SUP>65</SUP>Cu</HTML>', '<HTML><FONT color=010101></FONT><SUP>67</SUP>Zn</HTML>', '<HTML><FONT color=010101></FONT><SUP>69</SUP>Ga</HTML>', '<HTML><FONT color=010101></FONT><SUP>71</SUP>Ga</HTML>', '<HTML><FONT color=010101></FONT><SUP>73</SUP>Ge</HTML>', '<HTML><FONT color=010101></FONT><SUP>75</SUP>As</HTML>', '<HTML><FONT color=010101></FONT><SUP>77</SUP>Se</HTML>', '<HTML><FONT color=010101></FONT><SUP>79</SUP>Se</HTML>', '<HTML><FONT color=010101></FONT><SUP>79</SUP>Br</HTML>', '<HTML><FONT color=010101></FONT><SUP>81</SUP>Br</HTML>', '<HTML><FONT color=010101></FONT><SUP>83</SUP>Kr</HTML>', '<HTML><FONT color=010101></FONT><SUP>85</SUP>Kr</HTML>', '<HTML><FONT color=010101></FONT><SUP>85</SUP>Rb</HTML>', '<HTML><FONT color=010101></FONT><SUP>87</SUP>Rb</HTML>', '<HTML><FONT color=010101></FONT><SUP>87</SUP>Sr</HTML>', '<HTML><FONT color=010101></FONT><SUP>89</SUP>Y</HTML>', '<HTML><FONT color=010101></FONT><SUP>91</SUP>Zr</HTML>', '<HTML><FONT color=010101></FONT><SUP>93</SUP>Nb</HTML>', '<HTML><FONT color=010101></FONT><SUP>95</SUP>Mo</HTML>', '<HTML><FONT color=010101></FONT><SUP>97</SUP>Mo</HTML>', '<HTML><FONT color=010101></FONT><SUP>99</SUP>Tc</HTML>', '<HTML><FONT color=010101></FONT><SUP>99</SUP>Ru</HTML>', '<HTML><FONT color=010101></FONT><SUP>101</SUP>Ru</HTML>', '<HTML><FONT color=010101></FONT><SUP>103</SUP>Rh</HTML>', '<HTML><FONT color=010101></FONT><SUP>105</SUP>Pd</HTML>', '<HTML><FONT color=010101></FONT><SUP>107</SUP>Ag</HTML>', '<HTML><FONT color=010101></FONT><SUP>109</SUP>Ag</HTML>', '<HTML><FONT color=010101></FONT><SUP>111</SUP>Cd</HTML>', '<HTML><FONT color=010101></FONT><SUP>113</SUP>Cd</HTML>', '<HTML><FONT color=010101></FONT><SUP>113</SUP>In</HTML>', '<HTML><FONT color=010101></FONT><SUP>115</SUP>In</HTML>', '<HTML><FONT color=010101></FONT><SUP>115</SUP>Sn</HTML>', '<HTML><FONT color=010101></FONT><SUP>117</SUP>Sn</HTML>', '<HTML><FONT color=010101></FONT><SUP>119</SUP>Sn</HTML>', '<HTML><FONT color=010101></FONT><SUP>121</SUP>Sb</HTML>', '<HTML><FONT color=010101></FONT><SUP>123</SUP>Sb</HTML>', '<HTML><FONT color=010101></FONT><SUP>125</SUP>Sb</HTML>', '<HTML><FONT color=010101></FONT><SUP>123</SUP>Te</HTML>', '<HTML><FONT color=010101></FONT><SUP>125</SUP>Te</HTML>', '<HTML><FONT color=010101></FONT><SUP>127</SUP>I</HTML>', '<HTML><FONT color=010101></FONT><SUP>129</SUP>I</HTML>', '<HTML><FONT color=010101></FONT><SUP>129</SUP>Xe</HTML>', '<HTML><FONT color=010101></FONT><SUP>131</SUP>Xe</HTML>', '<HTML><FONT color=010101></FONT><SUP>133</SUP>Cs</HTML>', '<HTML><FONT color=010101></FONT><SUP>134</SUP>Cs</HTML>', '<HTML><FONT color=010101></FONT><SUP>135</SUP>Cs</HTML>', '<HTML><FONT color=010101></FONT><SUP>137</SUP>Cs</HTML>', '<HTML><FONT color=010101></FONT><SUP>133</SUP>Ba</HTML>', '<HTML><FONT color=010101></FONT><SUP>135</SUP>Ba</HTML>', '<HTML><FONT color=010101></FONT><SUP>137</SUP>Ba</HTML>', '<HTML><FONT color=010101></FONT><SUP>137</SUP>La</HTML>', '<HTML><FONT color=010101></FONT><SUP>138</SUP>La</HTML>', '<HTML><FONT color=010101></FONT><SUP>139</SUP>La</HTML>', '<HTML><FONT color=010101></FONT><SUP>141</SUP>Pr</HTML>', '<HTML><FONT color=010101></FONT><SUP>143</SUP>Nd</HTML>', '<HTML><FONT color=010101></FONT><SUP>145</SUP>Nd</HTML>', '<HTML><FONT color=010101></FONT><SUP>147</SUP>Pm</HTML>', '<HTML><FONT color=010101></FONT><SUP>147</SUP>Sm</HTML>', '<HTML><FONT color=010101></FONT><SUP>149</SUP>Sm</HTML>', '<HTML><FONT color=010101></FONT><SUP>151</SUP>Sm</HTML>', '<HTML><FONT color=010101></FONT><SUP>151</SUP>Eu</HTML>', '<HTML><FONT color=010101></FONT><SUP>152</SUP>Eu</HTML>', '<HTML><FONT color=010101></FONT><SUP>153</SUP>Eu</HTML>', '<HTML><FONT color=010101></FONT><SUP>154</SUP>Eu</HTML>', '<HTML><FONT color=010101></FONT><SUP>155</SUP>Eu</HTML>', '<HTML><FONT color=010101></FONT><SUP>155</SUP>Gd</HTML>', '<HTML><FONT color=010101></FONT><SUP>157</SUP>Gd</HTML>', '<HTML><FONT color=010101></FONT><SUP>157</SUP>Tb</HTML>', '<HTML><FONT color=010101></FONT><SUP>159</SUP>Tb</HTML>', '<HTML><FONT color=010101></FONT><SUP>160</SUP>Tb</HTML>', '<HTML><FONT color=010101></FONT><SUP>161</SUP>Dy</HTML>', '<HTML><FONT color=010101></FONT><SUP>163</SUP>Dy</HTML>', '<HTML><FONT color=010101></FONT><SUP>165</SUP>Ho</HTML>', '<HTML><FONT color=010101></FONT><SUP>167</SUP>Er</HTML>', '<HTML><FONT color=010101></FONT><SUP>169</SUP>Tm</HTML>', '<HTML><FONT color=010101></FONT><SUP>171</SUP>Tm</HTML>', '<HTML><FONT color=010101></FONT><SUP>171</SUP>Yb</HTML>', '<HTML><FONT color=010101></FONT><SUP>173</SUP>Yb</HTML>', '<HTML><FONT color=010101></FONT><SUP>173</SUP>Lu</HTML>', '<HTML><FONT color=010101></FONT><SUP>174</SUP>Lu</HTML>', '<HTML><FONT color=010101></FONT><SUP>175</SUP>Lu</HTML>', '<HTML><FONT color=010101></FONT><SUP>176</SUP>Lu</HTML>', '<HTML><FONT color=010101></FONT><SUP>177</SUP>Hf</HTML>', '<HTML><FONT color=010101></FONT><SUP>179</SUP>Hf</HTML>', '<HTML><FONT color=010101></FONT><SUP>180</SUP>Ta</HTML>', '<HTML><FONT color=010101></FONT><SUP>181</SUP>Ta</HTML>', '<HTML><FONT color=010101></FONT><SUP>183</SUP>W</HTML>', '<HTML><FONT color=010101></FONT><SUP>185</SUP>Re</HTML>', '<HTML><FONT color=010101></FONT><SUP>187</SUP>Re</HTML>', '<HTML><FONT color=010101></FONT><SUP>187</SUP>Os</HTML>', '<HTML><FONT color=010101></FONT><SUP>189</SUP>Os</HTML>', '<HTML><FONT color=010101></FONT><SUP>191</SUP>Ir</HTML>', '<HTML><FONT color=010101></FONT><SUP>193</SUP>Ir</HTML>', '<HTML><FONT color=010101></FONT><SUP>195</SUP>Pt</HTML>', '<HTML><FONT color=010101></FONT><SUP>197</SUP>Au</HTML>', '<HTML><FONT color=010101></FONT><SUP>199</SUP>Hg</HTML>', '<HTML><FONT color=010101></FONT><SUP>201</SUP>Hg</HTML>', '<HTML><FONT color=010101></FONT><SUP>203</SUP>Tl</HTML>', '<HTML><FONT color=010101></FONT><SUP>204</SUP>Tl</HTML>', '<HTML><FONT color=010101></FONT><SUP>205</SUP>Tl</HTML>', '<HTML><FONT color=010101></FONT><SUP>207</SUP>Pb</HTML>', '<HTML><FONT color=010101></FONT><SUP>207</SUP>Bi</HTML>', '<HTML><FONT color=010101></FONT><SUP>209</SUP>Bi</HTML>', '<HTML><FONT color=010101></FONT><SUP>209</SUP>Po</HTML>', '<HTML><FONT color=010101></FONT><SUP>227</SUP>Ac</HTML>', '<HTML><FONT color=010101></FONT><SUP>229</SUP>Th</HTML>', '<HTML><FONT color=010101></FONT><SUP>231</SUP>Pa</HTML>', '<HTML><FONT color=010101></FONT><SUP>235</SUP>U</HTML>', '<HTML><FONT color=010101></FONT><SUP>237</SUP>Np</HTML>', '<HTML><FONT color=010101></FONT><SUP>239</SUP>Pu</HTML>', '<HTML><FONT color=010101></FONT><SUP>243</SUP>Am</HTML>'};
            app.AddTagList.Tag = 'AddTagList';
            app.AddTagList.Enable = 'off';
            app.AddTagList.Value = '1H';
            app.ImposeBlindSpots = uicheckbox(app.AddOnsPanel);
            app.ImposeBlindSpots.ValueChangedFcn = createCallbackFcn(app, @ImposeBlindSpotsValueChanged, true);
            app.ImposeBlindSpots.Text = 'Blind Spots';
            app.CopyrightText = uilabel(app.HyscoreanFigure);
            app.CopyrightText.Tag = 'CopyrightText';
            app.CopyrightText.Text = '© 2018-2026 L. Fábregas Ibáñez & D. Klose';
            app.EasyspinFitButton = uibutton(app.HyscoreanFigure, 'push');
            app.EasyspinFitButton.ButtonPushedFcn = createCallbackFcn(app, @EasyspinFitButton_Callback, true);
            app.EasyspinFitButton.Tag = 'EasyspinFitButton';
            app.EasyspinFitButton.Enable = 'off';
            app.EasyspinFitButton.Tooltip = 'Launch EasySpin fit toolbox';
            app.EasyspinFitButton.Text = 'Fitting  Module';
            app.ZoomButton = uibutton(app.HyscoreanFigure, 'push');
            app.ZoomButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomButton_Callback, true);
            app.ZoomButton.Tag = 'ZoomButton';
            app.ZoomButton.Icon = 'ZoomButton_image.png';
            app.ZoomButton.Visible = 'off';
            app.ZoomButton.Text = '';
            app.ZoomOutButton = uibutton(app.HyscoreanFigure, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButton_Callback, true);
            app.ZoomOutButton.Tag = 'ZoomOutButton';
            app.ZoomOutButton.Icon = 'ZoomOutButton_image.png';
            app.ZoomOutButton.Visible = 'off';
            app.ZoomOutButton.Text = '';
            app.ImaginaryTrace = uicheckbox(app.HyscoreanFigure);
            app.ImaginaryTrace.ValueChangedFcn = createCallbackFcn(app, @ImaginaryTrace_Callback, true);
            app.ImaginaryTrace.Tag = 'ImaginaryTrace';
            app.ImaginaryTrace.Visible = 'off';
            app.ImaginaryTrace.Tooltip = 'Show Imaginary';
            app.ImaginaryTrace.Text = 'Imaginary';
            app.GPS_button = uibutton(app.HyscoreanFigure, 'state');
            app.GPS_button.ValueChangedFcn = createCallbackFcn(app, @GPS_button_Callback, true);
            app.GPS_button.Tag = 'GPS_button';
            app.GPS_button.Visible = 'off';
            app.GPS_button.Icon = 'GPS_button_image.png';
            app.GPS_button.Text = '';
            app.text97 = uilabel(app.HyscoreanFigure);
            app.text97.Tag = 'text97';
            app.text97.Text = 'Help';
            app.Image = uiimage(app.HyscoreanFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'docs', 'img', 'logo64.png');
            app.ChangeSignalPlotDimension = uicheckbox(app.HyscoreanFigure);
            app.ChangeSignalPlotDimension.Enable = 'off';
            app.ChangeSignalPlotDimension.Text = 't1 <-> t2';
            app.TraceButtonGroup = uibuttongroup(app.HyscoreanFigure);
            app.NonCorrectedTrace = uiradiobutton(app.TraceButtonGroup);
            app.NonCorrectedTrace.Text = 'NonCorrected';
            app.NonCorrectedTrace.Value = true;
            app.PreProcessedTrace = uiradiobutton(app.TraceButtonGroup);
            app.PreProcessedTrace.Text = 'PreProcessed';
            app.PlotApodizationWindow = uiradiobutton(app.TraceButtonGroup);
            app.PlotApodizationWindow.Text = 'Apodization';
            % Show the figure after all components are created
            app.HyscoreanFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Hyscorean(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.HyscoreanFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)Hyscorean_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.HyscoreanFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HyscoreanFigure)
        end
    end
end